classdef gait_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Tab1                         matlab.ui.container.Tab
        UploadDataButton             matlab.ui.control.Button
        MessageTextArea              matlab.ui.control.TextArea
        TextfromMCULabel             matlab.ui.control.Label
        BatteryPercentageGauge       matlab.ui.control.NinetyDegreeGauge
        BatteryPercentageGaugeLabel  matlab.ui.control.Label
        BluetoothConnectionPanel     matlab.ui.container.Panel
        StatusLamp                   matlab.ui.control.Lamp
        StatusLabel                  matlab.ui.control.Label
        DisconnectButton             matlab.ui.control.Button
        ConnectButton                matlab.ui.control.Button
        DeviceListDropDown           matlab.ui.control.DropDown
        DeviceListDropDownLabel      matlab.ui.control.Label
        ScanButton                   matlab.ui.control.Button
        DataVisualizationTab         matlab.ui.container.Tab
    end

    
    properties (Access = private)
        BLEDevice   % ble object
        DeviceTable % result of blelist command
        IsConnected = false
        TextChar    % The characteristic object
        DataTimer   % Timer to poll for data since 'subscribe' is missing
    end
    
    methods (Access = private)
        
        % Timer Callback: Reads and parses CSV data every 0.1 seconds
        function pollData(app, ~, ~)
            try
                data = read(app.TextChar);
        
                if ~isempty(data)
                    msg = strtrim(string(char(data)));
        
                    % Parse CSV: "VBAT:3.901,PCT:82,A0:1.234"
                    parts = strsplit(msg, ',');
        
                    if numel(parts) == 3
                        vbat = str2double(extractAfter(parts{1}, ':'));
                        pct  = str2double(extractAfter(parts{2}, ':'));
                        a0   = str2double(extractAfter(parts{3}, ':'));
        
                        % Update battery gauge
                        if ~isnan(pct)
                            app.BatteryPercentageGauge.Value = max(0, min(100, pct));
                        end
        
                        % Update text area — all char, no string mixing
                        if ~isnan(a0) && ~isnan(pct) && ~isnan(vbat)
                            timestamp = char(datetime("now", "Format", "HH:mm:ss"));
                            line1 = sprintf('[%s]  A1:  %.3f V', timestamp, a0);
                            line2 = sprintf('         BAT: %.3f V (%d%%)', vbat, int32(pct));
                            app.MessageTextArea.Value = {line1; line2};
                            drawnow;
                        end
        
                    else
                        % Fallback: raw string
                        timestamp = char(datetime("now", "Format", "HH:mm:ss"));
                        app.MessageTextArea.Value = {sprintf('[%s] RAW: %s', timestamp, char(msg))};
                        drawnow;
                    end
                end
        
            catch ME
                fprintf('Timer Error: %s\n', ME.message);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.StatusLabel.Text = "Scanning...";
            app.StatusLamp.Color = [1 1 0]; 
            app.DeviceTable = blelist; 
            
            if isempty(app.DeviceTable)
                app.StatusLabel.Text = "No BLE devices found";
                app.StatusLamp.Color = [1 0 0]; 
                return;
            end
            
            % Populate dropdown
            items = strcat(app.DeviceTable.Name, " (", app.DeviceTable.Address, ")");
            app.DeviceListDropDown.Items = items;
            app.StatusLabel.Text = "Scan complete";
            app.StatusLamp.Color = [1 1 0];

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if isempty(app.DeviceListDropDown.Value), return; end
            
            app.BLEDevice = []; 
            app.TextChar = [];
            
            try
                selected = app.DeviceListDropDown.Value;
                addr = extractBetween(selected, "(", ")");
                
                app.StatusLabel.Text = "Connecting...";
                app.StatusLamp.Color = [1 1 0];
                drawnow;

                % 1. Connect
                app.BLEDevice = ble(char(addr{1}));
                
                % 2. CASCADE: Try to find the characteristic using multiple methods
                servUUID = '12345678-1234-5678-1234-56789ABCDEF0';
                charUUID = '12345678-1234-5678-1234-56789ABCDEF1';
                
                success = false;
                
                % METHOD A: The Standard 3-Argument (Service UUID, Char UUID)
                if ~success
                    try
                        app.TextChar = characteristic(app.BLEDevice, servUUID, charUUID);
                        success = true;
                        fprintf('Connected using Method A (Standard UUIDs)\n');
                    catch
                    end
                end
                
                % METHOD B: Using Names from the table (Generic Access)
                % Sometimes older versions prefer the string "Name" over UUID
                if ~success
                    try
                        % Try to find the names in the table
                        tbl = app.BLEDevice.Characteristics;
                        row = strcmpi(tbl.CharacteristicUUID, charUUID);
                        if any(row)
                             sName = tbl.ServiceName(row);
                             cName = tbl.CharacteristicName(row);
                             % Try passing the Names (as char vectors)
                             if iscell(sName), sName = sName{1}; end
                             if iscell(cName), cName = cName{1}; end
                             
                             app.TextChar = characteristic(app.BLEDevice, char(sName), char(cName));
                             success = true;
                             fprintf('Connected using Method B (Names)\n');
                        end
                    catch
                    end
                end

                % METHOD C: The "Row Index" or "Single UUID" fallback
                if ~success
                    try
                        % Some very specific versions allow just the Characteristic UUID
                        app.TextChar = characteristic(app.BLEDevice, charUUID);
                        success = true;
                        fprintf('Connected using Method C (Single UUID)\n');
                    catch
                    end
                end
                
                if ~success
                     error("Could not create Characteristic object. All syntax methods failed.");
                end
                
                app.IsConnected = true;
                app.StatusLabel.Text = "Connected!";
                app.StatusLamp.Color = [0 1 0];

                % Reset display on fresh connection
                app.BatteryPercentageGauge.Value = 0;
                app.MessageTextArea.Value = {''};
                
                % 3. Start Polling Timer (Since 'subscribe' is missing)
                if ~isempty(app.DataTimer)
                    stop(app.DataTimer);
                    delete(app.DataTimer);
                end
                
                % Create a timer that fires 10 times a second
                app.DataTimer = timer('ExecutionMode', 'fixedRate', ...
                                      'Period', 0.1, ...
                                      'TimerFcn', @(~,~)app.pollData);
                start(app.DataTimer);
                fprintf('Polling timer started.\n');
        
            catch ME 
                app.StatusLabel.Text = "Connection failed";
                app.StatusLamp.Color = [1 0 0];
                fprintf('ERROR: %s\n', ME.message);
                
                % EMERGENCY DEBUG
                % If it fails, run this line in your Command Window to see the help docs:
                % help ble/characteristic
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            % Stop the timer first!
            if ~isempty(app.DataTimer)
                stop(app.DataTimer); 
                delete(app.DataTimer);
                app.DataTimer = [];
            end

            if app.IsConnected
                app.TextChar = [];
                app.BLEDevice = [];
                app.IsConnected = false;
        
                app.StatusLabel.Text = "Disconnected";
                app.StatusLamp.Color = [1 0 0];
            end
        end

        % Button pushed function: UploadDataButton
        function UploadDataButtonPushed(app, event)
 

            % determines what happens when the upload data button is pushed

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Connection, Upload, & Battery';
            app.BluetoothConnectionPanel = uipanel(app.Tab1);
            app.BluetoothConnectionPanel.Title = 'Bluetooth Connection';
            app.ScanButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.DeviceListDropDownLabel = uilabel(app.BluetoothConnectionPanel);
            app.DeviceListDropDownLabel.Text = 'Device List';
            app.DeviceListDropDown = uidropdown(app.BluetoothConnectionPanel);
            app.ConnectButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.StatusLabel = uilabel(app.BluetoothConnectionPanel);
            app.StatusLabel.Text = {'Status: '; 'Disconnected'};
            app.StatusLamp = uilamp(app.BluetoothConnectionPanel);
            app.BatteryPercentageGaugeLabel = uilabel(app.Tab1);
            app.BatteryPercentageGaugeLabel.Text = 'Battery Percentage';
            app.BatteryPercentageGauge = uigauge(app.Tab1, 'ninetydegree');
            app.TextfromMCULabel = uilabel(app.Tab1);
            app.TextfromMCULabel.Text = 'Text from MCU';
            app.MessageTextArea = uitextarea(app.Tab1);
            app.UploadDataButton = uibutton(app.Tab1, 'push');
            app.UploadDataButton.ButtonPushedFcn = createCallbackFcn(app, @UploadDataButtonPushed, true);
            app.UploadDataButton.Text = 'Upload Data';
            app.DataVisualizationTab = uitab(app.TabGroup);
            app.DataVisualizationTab.Title = 'Data Visualization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gait_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end